classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label               matlab.ui.control.Label
        ServerManageButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        server % TCPIP server object
        gui_timer
        current_number = 0
    end
    
    methods (Access = private)
        
        function results = onNewData(app, server, event)
            while (server.BytesAvailable >= 4)
                number = fread(server, 1, "int32");
                %disp(number);
                %app.Label.Text = num2str(number);
                %drawnow;
                app.current_number = number;
            end
        end
        
        function results = LaunchServer(app)
            disp("Started, waiting for connection...");
            app.server = tcpip('0.0.0.0',30000,'NetworkRole','server');
            app.server.BytesAvailableFcn = @app.onNewData;
            app.server.BytesAvailableFcnCount = 1;
            app.server.BytesAvailableFcnMode = 'byte';
            app.server.ReadAsyncMode = 'manual';
            app.server.InputBufferSize = 1024;
            fopen(app.server);
        end
        
        function results = StopServer(app)
            if (isprop(app, 'server') && ~isempty(app.server) && app.server.Status == "open")
                fclose(app.server);
                disp("Server stoped");
                %delete(app.server);
                %delete(app);
            end
        end
        
        function GUITimerCallBack(app, t, this)
            if (isprop(app, 'server') && ~isempty(app.server) && app.server.Status == "open")
            readasync(app.server);
            number = fread(app.server, 1, "uint32");
            app.Label.Text = num2str(number);
            end
            %drawnow();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gui_timer = timer('TimerFcn', @app.GUITimerCallBack, 'Period', 0.5, 'ExecutionMode','fixedRate','TasksToExecute', 10e6, 'Name', 'GUI_TIMER');
            start(app.gui_timer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            StopServer(app);
            stop(app.gui_timer);
            delete(app);
        end

        % Button pushed function: ServerManageButton
        function ServerManageButtonPushed(app, event)
            if (isprop(app, 'server') && ~isempty(app.server) && app.server.Status == "open")
                StopServer(app);
                app.ServerManageButton.Text = "Launch server";
            else
                app.ServerManageButton.Text = "Stop server";
                LaunchServer(app);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.ServerManageButton = uibutton(app.UIFigure, 'push');
            app.ServerManageButton.ButtonPushedFcn = createCallbackFcn(app, @ServerManageButtonPushed, true);
            app.ServerManageButton.Text = 'Launch server';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end